classdef BufferCapacity3 < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      UIFigure                       matlab.ui.Figure
      FileMenu                       matlab.ui.container.Menu
      OpenDatabaseFileMenu           matlab.ui.container.Menu
      OpenAcidTitrationFileMenu      matlab.ui.container.Menu
      OpenBaseTitrationFileMenu      matlab.ui.container.Menu
      OpenParameterFileMenu          matlab.ui.container.Menu
      SaveDatabaseFileMenu           matlab.ui.container.Menu
      SaveAllDataFilesMenu           matlab.ui.container.Menu
      SaveParameterFileMenu          matlab.ui.container.Menu
      QuitMenu                       matlab.ui.container.Menu
      HelpMenu                       matlab.ui.container.Menu
      OnlineHelpMenu                 matlab.ui.container.Menu
      AboutMenu                      matlab.ui.container.Menu
      TrimEndButton                  matlab.ui.control.Button
      TrimBeginingButton             matlab.ui.control.Button
      tBetaEditField                 matlab.ui.control.NumericEditField
      tBetaEditFieldLabel            matlab.ui.control.Label
      DataFolderEditField            matlab.ui.control.EditField
      DirLabel                       matlab.ui.control.Label
      ClearallButton                 matlab.ui.control.Button
      NaClpercentEditField           matlab.ui.control.NumericEditField
      NaClpercentEditFieldLabel      matlab.ui.control.Label
      ConcentrationintitrationEditField  matlab.ui.control.NumericEditField
      ConcentrationinTitrationLabel  matlab.ui.control.Label
      IngredientnameEditField        matlab.ui.control.EditField
      IngredientnameEditFieldLabel   matlab.ui.control.Label
      ModelSSEEditField              matlab.ui.control.NumericEditField
      ModelSSEEditFieldLabel         matlab.ui.control.Label
      useAdjCCheckBox                matlab.ui.control.CheckBox
      AdjCvalueMEditField            matlab.ui.control.NumericEditField
      AdjCvalueMEditFieldLabel       matlab.ui.control.Label
      EstimatedpHEditField           matlab.ui.control.NumericEditField
      EstimatedpHEditFieldLabel      matlab.ui.control.Label
      Electrode05pHEditField         matlab.ui.control.NumericEditField
      ElectrodeCorrection05pHLabel   matlab.ui.control.Label
      Lamp_ModelBC                   matlab.ui.control.Lamp
      ModelBCCurveButton             matlab.ui.control.Button
      VolumetitratedLEditField       matlab.ui.control.NumericEditField
      VolumetitratedLEditFieldLabel  matlab.ui.control.Label
      BaseConcentrationNEditField    matlab.ui.control.NumericEditField
      BaseConcentrationNEditFieldLabel  matlab.ui.control.Label
      AcidConcentrationNEditField    matlab.ui.control.NumericEditField
      AcidConcentrationNEditFieldLabel  matlab.ui.control.Label
      OKButton                       matlab.ui.control.Button
      TrimendEditField               matlab.ui.control.NumericEditField
      TrimbeginningEditField         matlab.ui.control.NumericEditField
      Lamp_BCcurve                   matlab.ui.control.Lamp
      GenerateBCcurveButton          matlab.ui.control.Button
      Lamp_basefile                  matlab.ui.control.Lamp
      OpenBaseTitrationFileButton    matlab.ui.control.Button
      Lamp_acidfile                  matlab.ui.control.Lamp
      OpenAcidTitrationFileButton    matlab.ui.control.Button
      BufferTable                    matlab.ui.control.Table
      UIAxes                         matlab.ui.control.UIAxes
   end

    properties (Access = private)
        AcidTitrationFilename = ''; %name of acid titration file 
        BaseTitrationFilename = ''; %name of base titration file
        measuredpH = -1;            %default pH 
        oriBCcurve = [];            %original BC curve from data
        BCcurve = [];               %trimmed BC curve
        D2B = struct;               %struct, results from Data2Beta.m
        SCBC = struct;              %struct, results from SCBCfit_only.m
        SPX = struct;               %struct, results from SimplexBCPK_DF5.m
        ParamTable = table;         %table with program parameters
        acidtitrationcrv = [];      %acid titration data (Nx2): pH, BC
        basetitrationcrv = [];      %base titration data (Nx2): pH, BC 
        ProgramLevel = 0;           %current sequential processing step
        saveall = 1;                %boolean: 1=all files, 0=ABD only 
        BufferCapacityVersion = '3.600'; %program version
        ABDversion = '320201'       %ABD class version
%         ABDversion = '220701';      %ABD class version 
    end
    
    methods (Access = private)
        
        function setWindowParams(app)
           %Set appropriate window parameters
            app.NaClpercentEditField.Value = ...
               app.ParamTable.Value('NaClpercent');            
            app.TrimbeginningEditField.Value = ...
               app.ParamTable.Value('Trim_beg');
            app.TrimendEditField.Value = app.ParamTable.Value('Trim_end');
            app.AcidConcentrationNEditField.Value = ...
               app.ParamTable.Value('HCl');
            app.BaseConcentrationNEditField.Value = ...
               app.ParamTable.Value('NaOH');
            app.VolumetitratedLEditField.Value = ...
               app.ParamTable.Value('Init_Vol');
        end

        function setParamTable(app, filename, delim)
           %get param table from file or use default
           try
              app.ParamTable = readtable(filename,'Delimiter',delim); 
              app.ParamTable.Properties.RowNames = ...
                 {'minDeltapH','Order','MinConc','NaClpercent','NpKs',...
          'UB','LB','pK_tol','Trim_beg','Trim_end','MinGap',...
          'Increment','HCl','NaOH','Init_Vol'};
           catch
              app.setParamTableDefault();
              msgbox('No parameter file found, loading default parameters',...
                 'BC3');
           end
        end

        function setParamTableDefault(app)
           %get default parameter table columns if no file read
           Value = [0.05;15;0.001;2;7;12;2;0.2;0;0;0.3;0.1;2;2;0.05];
           Parameter = {'pH';'Order';'MinConc';...
              'NaClpercent';'NpKs';'UB';'LB';'pK_tol';'Trim_beg';...
              'Trim_end';'MinGap';'Increment';'HCl';'NaOH';...
              'Init_Vol'};
           Units = {'pH';'Int';'Molar';'%';'Int';'pH';'pH';'pH';...
              'Int';'Int';'Float';'Float';'Normality';'Normality';...
              'Liter'}; 
           Description = {'Data2Beta to generate curve w/o jumps';...
            'SCBCfit number of harmonics';...
            'SCBCfit and SimplexBCPK_DF minimim buffer M/L';...
            'SimplexBCPK_DF NaCl percent' ;
            'SimplexBCPK_DF number of pKs for starting AB matrix';
            'SimplexBCPK_DF upper pH range for BC curve fit';
            'SimplexBCPK_DF lower pH range';
            'SimplexBCPK_DF pH range for too-similar pK vals';
            'DriveFit trim off begining to balance end';
            'DriveFit trim off end to balance begining';
            'Minimum gap in pH for not autofilling BC curve';
            'Increment pH value for gap filling of BC curve';
            'Normality of HCl used for titration';
            'Normalty of NaOH used for titration';
            'Volume (L) of initial titration'};
            %make table and set row names
            app.ParamTable = table(Value,Parameter,Units,Description);
            app.ParamTable.Properties.RowNames = ...
               {'minDeltapH','Order','MinConc','NaClpercent','NpKs',...
               'UB','LB','pK_tol','Trim_beg','Trim_end','MinGap',...
               'Increment','HCl','NaOH','Init_Vol'};
        end

        function [pH, adjC] = optimize(app,value)
           %set adjC and pH
           if app.ProgramLevel == 5
              tablemat = app.BufferTable.Data; %get conc-pK table data
              if value %make sure table has data
                 adj_factor = GetAdjCT(app.measuredpH,...
                 tablemat,app.ParamTable.Value('NaClpercent')); %adjC value
                 %set GUI adjC value and predicted pH from the table
                 adjC = adj_factor.adjC;
                 pH = CalcpH_ABT(tablemat,...
                    app.ParamTable.Value('NaClpercent'),adjC);
              else
                 adjC = 0;
                 pH = CalcpH_ABT(tablemat,...
                    app.ParamTable.Value('NaClpercent'),0);
              end
           end
        end
      
        function level = setProgramLevel(app,stepval)
           %control lamps indication processing step
           if stepval <= 4
              app.clearmodel();
           end
           switch stepval
              case 0 %default no file open
                 app.Lamp_acidfile.Color = 'white';
                 app.Lamp_basefile.Color = 'white';
                 app.Lamp_BCcurve.Color = 'white';
                 app.Lamp_ModelBC.Color = 'white';
                 app.OKButton.BackgroundColor = 'white';
                 app.Electrode05pHEditField.Enable = 'off';
                 app.TrimbeginningEditField.Enable = 'off';
                 app.TrimendEditField.Enable = 'off';
                 app.setInitialHighlight(1);
                 app.setCurveEdit(0);
                 app.DataFolderEditField.Value = "";
                 level = 0;
              case 1 %the acid file is opened and displayed
                 app.Lamp_acidfile.Color = 'green';
                 app.Lamp_basefile.Color = 'white';
                 app.Lamp_BCcurve.Color = 'white';
                 app.Lamp_ModelBC.Color = 'white';
                 app.OKButton.BackgroundColor = 'white';
                 app.Electrode05pHEditField.Enable = 'off';
                 app.TrimbeginningEditField.Enable = 'off';
                 app.TrimendEditField.Enable = 'off';
                 app.setInitialHighlight(0);
                 app.setCurveEdit(0);
                 level = 1;
              case 2 %the base file is opened and displayed
                 app.Lamp_acidfile.Color = 'green';
                 app.Lamp_basefile.Color = 'green';
                 app.Lamp_BCcurve.Color = 'white';
                 app.Lamp_ModelBC.Color = 'white';
                 app.OKButton.BackgroundColor = 'white';
                 app.Electrode05pHEditField.Enable = 'off';
                 app.TrimbeginningEditField.Enable = 'off';
                 app.TrimendEditField.Enable = 'off';
                 app.setInitialHighlight(0);
                 app.setCurveEdit(0);
                 level = 2;
              case 3 %both the acid and base files are open and displayed
                 app.Lamp_acidfile.Color = 'green';
                 app.Lamp_basefile.Color = 'green';
                 app.Lamp_BCcurve.Color = 'green';
                 app.Lamp_ModelBC.Color = 'white';
                 app.OKButton.BackgroundColor = 'white';
                 app.Electrode05pHEditField.Enable = 'on';
                 app.TrimbeginningEditField.Enable = 'on';
                 app.TrimendEditField.Enable = 'on';
                 app.setInitialHighlight(0);
                 app.setCurveEdit(1);
                 level = 3;
              case 4 %both the acid and base files are open and displayed
                 app.Lamp_acidfile.Color = 'green';
                 app.Lamp_basefile.Color = 'green';
                 app.Lamp_BCcurve.Color = 'green';
                 app.Lamp_ModelBC.Color = 'white';
                 app.OKButton.BackgroundColor = 'green';
                 app.Electrode05pHEditField.Enable = 'on';
                 app.TrimbeginningEditField.Enable = 'on';
                 app.TrimendEditField.Enable = 'on';
                 app.setInitialHighlight(0);
                 app.setCurveEdit(0);
                 level = 4; 
              case 5 %all data present, needed for opening database file
                 app.Lamp_acidfile.Color = 'green';
                 app.Lamp_basefile.Color = 'green';
                 app.Lamp_BCcurve.Color = 'green';
                 app.Lamp_ModelBC.Color = 'green';
                 app.OKButton.BackgroundColor = 'green';
                 app.Electrode05pHEditField.Enable = 'on';
                 app.TrimbeginningEditField.Enable = 'on';
                 app.TrimendEditField.Enable = 'on';
                 app.setInitialHighlight(0);
                 app.setCurveEdit(0);
                 level = 5;
           end
        end
      
       function plottitration(app)
          %plot the titration data (BC vs pH)
          cla(app.UIAxes,'reset');                  %clear the plot
          app.UIAxes.NextPlot = 'replacechildren';  %reset axes
          app.UIAxes.Title.String = 'Titration data'; %Title
          app.UIAxes.YLabel.String = 'pH';          %Y axis label
          app.UIAxes.XLabel.String = 'Volume added (ml)'; %X axis label
          maxacidvol = 0;                         
          if ~isempty(app.acidtitrationcrv) 
             maxacidvol = max(app.acidtitrationcrv(:,1)); %get range (acid)
             plot(app.UIAxes,app.acidtitrationcrv(:,1),...
                app.acidtitrationcrv(:,2),"or");
          end
          hold(app.UIAxes,"on");
          if ~isempty(app.basetitrationcrv)
             maxbasevol = max(app.basetitrationcrv(:,1)); %get range (base)
             if maxbasevol > maxacidvol             %check correct range
                maxval = maxbasevol;
             else
                maxval = maxacidvol;
             end
             app.UIAxes.XLim = [0, maxval];          %set range for graph
             plot(app.UIAxes,app.basetitrationcrv(:,1),...
                app.basetitrationcrv(:,2),"ob");
          end
          hold(app.UIAxes,"off");
       end

       function plotBCdata(app, oricurve,fillcurve)
          %Plot the compisite graph with trimming and fills
          cla(app.UIAxes,'reset');                  %clear graph
          app.UIAxes.NextPlot = 'replacechildren';  %preset axes
          app.UIAxes.Title.String = 'BC Curve';     %Title
          app.UIAxes.YLabel.String = 'Beta';        %Y Axis label
          app.UIAxes.XLabel.String = 'pH';          %X Axis label
          app.UIAxes.XLim = [1,13];                 %X axis range
          app.UIAxes.YLim = [0, inf];               %Y axis range
          %generate plot
          plot(app.UIAxes,fillcurve(:,1),fillcurve(:,2),'ok',...
             oricurve(:,1),oricurve(:,2),'ob');
          hold(app.UIAxes,"on");                    %hold for water plot
          maxBC = max(fillcurve(:,2));              %estimate water BC
          start = 1.5;                              %pH vec start
          finish = 12.5;                            %pH vec end
          for i=1:10                                %iter for height
             waterpHvec = start:0.05:finish;        %pH vec for water
             %data for water plot
             WC = BetaModel_AB([0 0],app.ParamTable.Value('NaClpercent'),...
                waterpHvec');
             maxWC = max(WC(:,2));                  %get max val
             %ad-hoc method for adjusting water curve height
             if maxWC < 1.7*maxBC         %check height of water curve
                break;
             else
                start = start + (i*0.075);          %adjust start val
                finish = finish - (i*0.1);          %adjust end val
             end
          end
          plot(app.UIAxes,WC(:,1),WC(:,2),'.k');    %plot water curve
          hold(app.UIAxes,"off");                   %end hold
       end

       function letters = getAB_letters(~,abmat)
          %Get "a" or "b" letters for acid or base
          rows = size(abmat,1);                     %get size of matrix
          letters = repmat('a',rows,1);             %make a char vec
          if rows > 0                               %if buffers
             for i=1:rows                           %for each buffer
                if abmat(i,2) > 7                   %check pH
                   letters(i) = 'b';                %reset if above 7
                end
             end
          end
       end
   
      function cleardata(app)
         %reset data members to initial default values (see Properties)
         app.ProgramLevel = 0;
         app.BufferTable.Data = table;
         app.AcidTitrationFilename = '';
         app.BaseTitrationFilename = '';
         cla(app.UIAxes,'reset');
         app.UIAxes.NextPlot = 'replacechildren';  %reset axes
         app.measuredpH = -1;
         app.BCcurve = [];
         app.oriBCcurve = [];
         app.D2B = struct;
         app.SCBC = struct;
         app.SPX = struct;
         app.acidtitrationcrv = [];
         app.basetitrationcrv = [];
         app.ProgramLevel = 0;                %initialize program level
         app.ModelSSEEditField.Value = 0;     %dialog box values...
         app.EstimatedpHEditField.Value = 0;
         app.AdjCvalueMEditField.Value = 0;
         app.tBetaEditField.Value = 0;
         app.IngredientnameEditField.Value = 'Ingredient';
         app.ConcentrationintitrationEditField.Value = 100;
         temp = ABD;                   %get current ABD
         app.ABDversion = temp.ver;    %set version number
         app.ConcentrationintitrationEditField.Value = 100;
      end
      
      function clearmodel(app) %just clear BC model results
         app.BufferTable.Data = table;
         app.ModelSSEEditField.Value = 0;
         app.EstimatedpHEditField.Value = 0;
         app.AdjCvalueMEditField.Value = 0;
         app.tBetaEditField.Value = 0;
      end

      function infotable = get_infotable(app)
         %data for output "info" file
         Value = [app.ConcentrationintitrationEditField.Value; ...
            app.NaClpercentEditField.Value; ...
            app.VolumetitratedLEditField.Value; ...
            app.AcidConcentrationNEditField.Value; ...
            app.BaseConcentrationNEditField.Value; ...
            app.measuredpH; ...
            app.EstimatedpHEditField.Value; ...
            app.AdjCvalueMEditField.Value;]; 
         Parameter = {'IngredientConcentration'; ...
            'NaClpercent';'InitialVolume';'AcidConc'; ...
            'BaseConc';'MeasuredpH';'ModelpH';'AdjC'};
         Units = {'Percent';'Percent';'Liter';'Molar';'Molar';'pH'; ...
            'pH';'Molar'};
         Description = {'Concentration of ingredient in titraiton'; ...
            'Percent NaCl in titration'; ...
            'Initial volume of titration solution'; ...
            'HCl concentation used for titration'; ...
            'NaOH concentration used for titration'; ...
            'pH of solution before titration'; ...
            'pH estimated by model'; ...
            'AdjC value (salts of acid or base)'};
            %make table and set row names
            infotable = table(Parameter,Value,Units,Description);
            infotable.Properties.RowNames = ...
               {'IngredientConc','NaClpercent','InitialVol',...
               'HClconc','NaOHconc','InitialpH','ModelpH','adjCval'};
      end
      
      function setInitialHighlight(app,boolval)
         %set edit field background colors. 
         Y = [0.92,0.82,0.59];
         W = 'w';
         if boolval == 1
            app.IngredientnameEditField.BackgroundColor = Y;
            app.ConcentrationintitrationEditField.BackgroundColor = Y;
            app.AcidConcentrationNEditField.BackgroundColor = Y;
            app.BaseConcentrationNEditField.BackgroundColor = Y;
            app.VolumetitratedLEditField.BackgroundColor = Y;
            app.NaClpercentEditField.BackgroundColor = Y;
         else
            app.IngredientnameEditField.BackgroundColor = W;
            app.ConcentrationintitrationEditField.BackgroundColor = W;
            app.AcidConcentrationNEditField.BackgroundColor = W;
            app.BaseConcentrationNEditField.BackgroundColor = W;
            app.VolumetitratedLEditField.BackgroundColor = W;
            app.NaClpercentEditField.BackgroundColor = W;
         end
      end
      
      function setDataFolder(app,pathname)
         %directory with titration data files
         if ~strcmp(pathname,"")
            parts = split(pathname,filesep); %makes cell array
            folder = parts(end-1);           %last item is seperator
            app.DataFolderEditField.Value = string(folder); %show folder
         end
      end
      
      function setCurveEdit(app,boolval)
         %background for curve edit fields
         Y = [0.92,0.82,0.59];   %amber color
         W = 'w';                %white has a name 'white' or 'w'
         if boolval == 1
            app.Electrode05pHEditField.BackgroundColor = Y;
            app.TrimbeginningEditField.BackgroundColor = Y;
            app.TrimendEditField.BackgroundColor = Y;
         else
            app.Electrode05pHEditField.BackgroundColor = W;
            app.TrimbeginningEditField.BackgroundColor = W;
            app.TrimendEditField.BackgroundColor = W;
         end
      end
      
      function Plot_tBetaGraph(app, tBetainfo, bccurve, bcmat)
         %data for graph
         spxcurve = tBetainfo.BCcrv;   %get the simplex curve
         WC = tBetainfo.Watercrv;      %get water curve for graph
         %plot the optimzed buffer capacity curve
         cla(app.UIAxes,'reset');
         app.UIAxes.NextPlot = 'replacechildren';  %reset axes
         app.UIAxes.Title.String = 'Buffer Capacity Model';  %Title
         app.UIAxes.YLabel.String = 'Beta';        %Y Axis label
         app.UIAxes.XLabel.String = 'pH';          %X Axis label
         app.UIAxes.XLim = [1,13];                 %X axis range
         app.UIAxes.YLim = [0, inf];               %inf=default
         enableDefaultInteractivity(app.UIAxes);
         rows = size(bcmat,1);                     %BCmatrix size
         %plot BC curve data
         plot(app.UIAxes,bccurve(:,1),bccurve(:,2),"ok",spxcurve(:,1),...
            spxcurve(:,2),'-k');
         hold(app.UIAxes,"on");                    %Additions:
         HI = area(app.UIAxes,spxcurve(:,1),spxcurve(:,2),'FaceColor',...
            [0.8, 0.8 0.8]);
         alpha(HI,0.5);
         if bcmat(1,1) ~= 0                        %If BC conc vals
            for i=1:rows                           %plot pK lines
               line(app.UIAxes,[bcmat(i,2),bcmat(i,2)],[0,bcmat(i,1)],...
                  'color','black');
            end
            maxBC = max(bccurve(:,2));             %max BC val
            start = 1;                             %water curve pH
            finish = 13;                           %max pH
            for i=1:10
               waterpHvec = start:0.05:finish;     %make vector
               %Water curve data
               WC = BetaModel_AB([0 0],...
                  app.ParamTable.Value('NaClpercent'),...
                  waterpHvec');
               maxWC = max(WC(:,2));               %get max
               %ad-hoc method for adjusting water curve height
               if maxWC < 1.7*maxBC                %check height
                  break;
               else
                  start = start + (i*0.075);       %adjust pH start
                  finish = finish - (i*0.1);       %pH end
               end
            end

            HI = area(app.UIAxes,WC(:,1),WC(:,2),'FaceColor',[1,1,1],...
               'LineStyle',':'); %plot water curve
            alpha(HI, 0.5);
            hold(app.UIAxes,"off");                %end hold
         end
      end
    end
    

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function startupFcn(app)
         app.setParamTable('paramfile.csv',',');
         app.setWindowParams();        %defalult parameters
         app.IngredientnameEditField.Value = 'Ingredient';
         app.ConcentrationintitrationEditField.Value = 100;
         app.setProgramLevel(0);       %set program level
         app.plottitration();          %sets axes values
         temp = ABD;                   %get current ABD
         app.saveall = 1;
         app.ABDversion = temp.ver;    %set version number
         app.UIAxes.Interactions = [regionZoomInteraction ... 
            rulerPanInteraction];
         pathname = pwd;
         parts = split(pathname,filesep); %makes cell array
         folder = parts(end);           %last item is seperator
         app.DataFolderEditField.Value = string(folder); %show folder
      end

      % Menu selected function: AboutMenu
      function AboutMenuSelected(app, event)
         msgbox({'BufferCapacity Version 3.523';'Fred Breidt USDA/ARS'; ...
      'USDA/ARS Food Science Market Quality and Handling Research Unit'; ...
      '322 Schaub Hall, Box 7624 NC State University, Raleigh NC'}, ...
      'About')
      end

      % Menu selected function: OpenParameterFileMenu
      function OpenParameterFileMenuSelected(app, event)
         %open a parameter file to load custom parameters
         try
            [file, filepath] = uigetfile('paramfile.csv','*.csv');
            filename = fullfile(filepath, file);  %assembles full path
            %Matlab readtable function with comma delimiter
            if file %not cancel
               app.setParamTable(filename,',');
               cd(filepath);
               app.setDataFolder(filepath);
            end
         catch
            msgbox(['Error loading parameter file.',...
               ' Please check filepath'], ...
               'File error');
         end
      end

      % Menu selected function: SaveParameterFileMenu
      function SaveParameterFileMenuSelected(app, event)
         try
            [file, filepath] = uiputfile('paramfile.csv','*.csv');
            pfile_pathname = fullfile(filepath, file);  %full path
            %save parameters to .csv file method
            if file %not cancel
               writetable(app.ParamTable,pfile_pathname); %write to file
            end
         catch
            msgbox(['Error saving parameter file.',...
                  ' Please check filepath'], ...
                  'File error'); 
         end
      end

      % Button pushed function: OpenAcidTitrationFileButton
      function OpenAcidTitrationFileButtonPushed(app, event)
         try
            [filename, filepath] = uigetfile({'*.RPT';'*.*'},...
            "Open acid RPT file");                 %read acid curve
            if filename %not cancel!
               app.setDataFolder(filepath);
               app.AcidTitrationFilename = filename;
               cd(filepath)
               infile = fullfile(filepath, filename); %assembles full path
               app.acidtitrationcrv = readRPT(infile); %get data matrix
               if app.ProgramLevel >= 1
                  %erase all after level 1
                  app.basetitrationcrv = [];
                  app.plottitration();
                  app.ProgramLevel = app.setProgramLevel(1);
               else
                  app.plottitration();
                  app.ProgramLevel = app.setProgramLevel(1);
               end
            end

         catch
            msgbox(['Error Reading base titration file.',...
               ' Please check .RPT file text'], ...
               'File error');
            app.ProgramLevel = app.setProgramLevel(0);
         end
      end

      % Button pushed function: OpenBaseTitrationFileButton
      function OpenBaseTitrationFileButtonPushed(app, event)
         dlgans = 'Default';
         if app.ProgramLevel >=2
            dlgans = questdlg('Use existing acid titration data?', ...
               'Confirm', 'Yes','No', 'Yes');
         end 
         if strcmp(dlgans,'No')
             app.ProgramLevel = app.setProgramLevel(0);
             app.cleardata();
             app.plottitration();
         elseif (app.ProgramLevel == 1) || strcmp(dlgans,'Yes')
            try
               [filename, filepath] = uigetfile({'*.RPT';'*.*'},...
               "Open base RPT file");                 %read acid curve
               if filename %not cancel
                  app.setDataFolder(filepath);
                  app.BaseTitrationFilename = filename;
                  cd(filepath)
                  infile = fullfile(filepath, filename); %assembles full path
                  app.basetitrationcrv = readRPT(infile); %get data matrix
                  app.ProgramLevel = app.setProgramLevel(2);
                  app.plottitration();
               end
            catch
               msgbox(['Error Reading base titration file.',...
                  ' Please check .RPT file text'], ...
                  'File error');
            end
            
            %get measured pH
            initialpHacid = app.acidtitrationcrv(1,2);
            initialpHbase = app.basetitrationcrv(1,2);
            app.measuredpH = (initialpHacid + initialpHbase)/2;
         end
      end

      % Button pushed function: GenerateBCcurveButton
      function GenerateBCcurveButtonPushed(app, event)
         if app.ProgramLevel >= 2
            %make and plot buffer curve data
            app.D2B = Data2Beta(app.acidtitrationcrv,...
               app.basetitrationcrv,...
               app.ParamTable.Value('HCl'),...
               app.ParamTable.Value('NaOH'),...
               app.ParamTable.Value('Init_Vol'),...
               app.ParamTable.Value('minDeltapH'));
               app.BCcurve = FillGaps(app.D2B.BCcurve,...
                  app.ParamTable.Value('MinGap'),...
                  app.ParamTable.Value('Increment'));
               app.oriBCcurve = app.BCcurve;
               app.plotBCdata(app.D2B.BCcurve,app.BCcurve);
            app.ProgramLevel = app.setProgramLevel(3);
         end
      end

      % Button pushed function: OKButton
      function OKButtonPushed(app, event)
         if app.ProgramLevel >= 3
            trimbeg = app.TrimbeginningEditField.Value;
            trimend = app.TrimendEditField.Value;
            [BCpts, ~] = size(app.oriBCcurve);     %get total data pts
            app.BCcurve = app.oriBCcurve(trimbeg+1:BCpts,:);
            [BCpts, ~] = size(app.BCcurve);     %get total data pts
            app.BCcurve = app.BCcurve(1:BCpts-trimend,:);
            value = app.Electrode05pHEditField.Value;         
            %this can fix pH electrode calibration errors
            tempBC = app.BCcurve;
            tempBC(:,1) = tempBC(:,1) + value;
            app.BCcurve = tempBC;
            app.plotBCdata([0,0],app.BCcurve);
            app.ProgramLevel = app.setProgramLevel(4);
         else
            app.TrimbeginningEditField.Value = 0;
            app.TrimendEditField.Value = 0;
            app.Electrode05pHEditField.Value = 0;
         end
      end

      % Button pushed function: ModelBCCurveButton
      function ModelBCCurveButtonPushed(app, event)
         if app.ProgramLevel >= 4
            app.ProgramLevel = app.setProgramLevel(5);
            app.SCBC = SCBCfit_only(app.BCcurve, ...
               app.ParamTable.Value('Order')); %get trig regression data
            scbcpred = app.SCBC.Pred;
            psize = size(scbcpred,1); %number of data pts
            %initialize optimization with pK distribution across pts
            npks = app.ParamTable.Value('NpKs');
            indxvec = splitvec(npks, psize);
            %initialize temporary matrix for initial conc-pK values
            tempMat = zeros(npks,2);
            for k = 1:npks    %for each pK assign:
               tempMat(k,1) = scbcpred(indxvec(k),2); %BC value
               tempMat(k,2) = scbcpred(indxvec(k),1); %pK value
            end
            %use Matlab's fmincon with tol = 1e-4 and maxevals = 1e4
            app.SPX = SimplexBCPK_DF5(scbcpred,tempMat,...
                  app.ParamTable.Value('MinConc'),...
                  app.ParamTable.Value('pK_tol'),... 
                  app.ParamTable.Value('LB'),...
                  app.ParamTable.Value('UB'));
            letters = app.getAB_letters(app.SPX.ABmat); %acid/base code
            %make a table with results
            buftable = table(app.SPX.ABmat(:,1), app.SPX.ABmat(:,2),...
                  cellstr(letters), app.SPX.BCmat(:,1));
            app.BufferTable.Data = buftable;    %assign table to GUI
            if app.SPX.exitflag == 1
               app.Lamp_ModelBC.Color = 'green';   %green = complete
            else
               app.Lamp_ModelBC.Color = 'yellow';  %yellow = incomplete
            end
            %post SSE for model
            app.ModelSSEEditField.Value = app.SPX.SSE;
            %post pH with or without adjC:
            app.useAdjCCheckBoxValueChanged();
            %post area under curve;
            ABmat = app.SPX.ABmat(:,1:2);
            minpH = min(scbcpred(:,1));
            maxpH = max(scbcpred(:,1));
            waterNaCl = app.NaClpercentEditField.Value/5.84;
            tBetainfo = get_tBetaData(ABmat,minpH,maxpH,waterNaCl,0);
            app.tBetaEditField.Value = tBetainfo.tBeta;
            %plot
            app.Plot_tBetaGraph(tBetainfo,app.BCcurve,app.SPX.BCmat);
            %app.PlotSPXcurve(app.BCcurve,app.SPX.Pred,app.SPX.BCmat);
         end
      end

      % Value changed function: useAdjCCheckBox
      function useAdjCCheckBoxValueChanged(app, event)
         %
         if app.ProgramLevel == 5
            value = app.useAdjCCheckBox.Value;
            [pH, adjC] = app.optimize(value);
            app.EstimatedpHEditField.Value = pH;
            app.AdjCvalueMEditField.Value = adjC;
         end
      end

      % Button pushed function: ClearallButton
      function ClearallButtonPushed(app, event)
         app.cleardata();
         app.ProgramLevel = app.setProgramLevel(0);
         app.setInitialHighlight(1);
         app.setDataFolder("");
         app.plottitration();          %sets axes values
      end

      % Menu selected function: OpenDatabaseFileMenu
      function OpenDatabaseFileMenuSelected(app, event)
         [file, filepath] = uigetfile({'*.mat';'*.*'},...
            "Open .mat database file"); 
         if file == 0
               return      
         else %not cancel
            try 
               app.ClearallButtonPushed();
               app.ProgramLevel = app.setProgramLevel(5);
               %post dialog to save existing data if step 4 completed...
               file = fullfile(filepath, file);  %assembles full path
               cd(filepath);
               app.setDataFolder(filepath);
               load(file,'ingredient');
               if ingredient.titration == 0
                  msgbox(['Error Reading database file.',...
                  ' File does not have titration data'], ...
                  'File error');
                  clear('ingredient');
                  return
               end
               %setup parameter table
               tempparams = ingredient.parameters.Value;
               if ~isnumeric(tempparams)
                  tempparams = str2double(tempparams);
               end
               app.setParamTableDefault(); %set basic values
               app.ParamTable.Value = tempparams; %set updated values
               %set window data fields
               app.IngredientnameEditField.Value = ...
                  ingredient.name;
               app.ConcentrationintitrationEditField.Value = ...
                  ingredient.titration_conc;
               app.setWindowParams();
               %trimmed data will be put in curve below but set here...
               app.TrimbeginningEditField.Value = app.ParamTable.Value('Trim_beg');
               app.TrimendEditField.Value = app.ParamTable.Value('Trim_end');
               %set titration file names
               app.AcidTitrationFilename = ...
                  ingredient.titration_files.HCl_titration_filename;
               app.BaseTitrationFilename = ...
                  ingredient.titration_files.NaOH_titration_filename;
               %set titration curves
               app.acidtitrationcrv = ...
                  ingredient.titration_files.HCl_titration_data;
               app.basetitrationcrv = ...
                  ingredient.titration_files.NaOH_titration_data;
               %set measured pH
      if ~isempty(ingredient.titration_files.HCl_titration_data) && ...
            ~isempty(ingredient.titration_files.NaOH_titration_data)
         avepH = (ingredient.titration_files.HCl_titration_data(1,2) +...
            ingredient.titration_files.NaOH_titration_data(1,2))/2;
         app.measuredpH = avepH;
      end
               %set BCcurve
               app.oriBCcurve = ingredient.titration_BCcurve; %has original data
               %ingredient.titration_oricurve?
               %app.oriBCcurve = app.BCcurve; %BC curve has original data
               %set  app.D2B = struct;
               app.D2B = Data2Beta(app.acidtitrationcrv,...
                  app.basetitrationcrv,...
                  app.ParamTable.Value('HCl'),...
                  app.ParamTable.Value('NaOH'),...
                  app.ParamTable.Value('Init_Vol'),...
                  app.ParamTable.Value('minDeltapH'));
               app.OKButtonPushed();
               app.ProgramLevel = app.setProgramLevel(5); %reset to level 5 (OK-> level4)
               app.SCBC = SCBCfit_only(app.BCcurve, ...
                  app.ParamTable.Value('Order'));
               scbcpred = app.SCBC.Pred;
               %crv_area = app.SCBC.area;
               %set app.SPX = struct;
               app.SPX = ingredient.titration_SPX;
               if isfield(app.SPX, 'ABmat') %does SPX have data?
                  %set buffertable
                  letters = app.getAB_letters(app.SPX.ABmat); %acid/base code
                  newletters = cellstr(letters);
                  oldletters = ingredient.buffer_table.a_b;
                  if ~isequal(oldletters,newletters)
                     letters = oldletters;
                  else
                     letters = newletters;
                  end
                  buftable = table(app.SPX.ABmat(:,1), ...
                     app.SPX.ABmat(:,2),...
                        letters, app.SPX.BCmat(:,1));
                  app.BufferTable.Data = buftable;    %assign table to GUI
                  %set adjC checkbox to checked (default)
                  if app.SPX.exitflag == 1
                     app.Lamp_ModelBC.Color = 'green';   %green = complete
                  else
                     app.Lamp_ModelBC.Color = 'yellow';  %yellow = incomplete
                  end
                  %set SSE for model and pH and area
                  app.ModelSSEEditField.Value = app.SPX.SSE;
                  %calc pH here?
                  app.useAdjCCheckBoxValueChanged();
                  %post tBeta 
                  ABmat = app.SPX.ABmat(:,1:2);
                  minpH = min(scbcpred(:,1));
                  maxpH = max(scbcpred(:,1));
                  waterNaCl = app.NaClpercentEditField.Value/5.84;
                  tBetainfo = get_tBetaData(ABmat,minpH,maxpH,waterNaCl,0);
                  app.tBetaEditField.Value = tBetainfo.tBeta;
                  %plot
                  app.Plot_tBetaGraph(tBetainfo,app.BCcurve,app.SPX.BCmat);
                  app.ABDversion = ingredient.ver;    %set version number
                  %plot
               else
                  app.plottitration();
                  app.ProgramLevel = app.setProgramLevel(2);
               end
            catch
               app.ClearallButtonPushed();
               msgbox(['Error Reading database file.',...
                  ' Please check .mat file format'], ...
                  'File error');
            end
         end

      end

      % Menu selected function: SaveDatabaseFileMenu
      function SaveDatabaseFileMenuSelected(app, event)
         app.saveall = 0;
         app.SaveAllDataFilesMenuSelected();
      end

      % Value changed function: Electrode05pHEditField
      function Electrode05pHEditFieldValueChanged(app, event)
         value = app.Electrode05pHEditField.Value; 
         %use slider to adjust pH values of curve
         %this can fix pH electrode calibration errors
         if app.ProgramLevel >= 0.3
            tempBC = app.BCcurve;
            tempBC(:,1) = tempBC(:,1) + value;
            app.BCcurve = tempBC;
            app.plotBCdata([0,0],app.BCcurve);
            app.ProgramLevel = app.setProgramLevel(3);
         end
      end

      % Value changed function: TrimbeginningEditField
      function TrimbeginningEditFieldValueChanged(app, event)
         newval = app.TrimbeginningEditField.Value;
         app.ParamTable.Value('Trim_beg') = newval;
         app.OKButtonPushed();
      end

      % Value changed function: TrimendEditField
      function TrimendEditFieldValueChanged(app, event)
         newval = app.TrimendEditField.Value;
         app.ParamTable.Value('Trim_end') = newval;
         app.OKButtonPushed();
      end

      % Cell edit callback: BufferTable
      function BufferTableCellEdit(app, event)
         indices = event.Indices;
         newData = {event.NewData};
         if strcmp(newData, 'a') || strcmp(newData, 'b')
            BTrow = indices(1);
            BTcol = indices(2);
            app.BufferTable.Data(BTrow,BTcol) = newData;
            %reset pH using:
            app.useAdjCCheckBoxValueChanged();
         end
         
      end

      % Menu selected function: OnlineHelpMenu
      function OnlineHelpMenuSelected(app, event)
webstr = 'https://www.ars.usda.gov/southeast-area/raleigh-nc/fsmqhru/docs/help-instructions-for-buffercapacity3/';
         stat = web(webstr,'-browser');
         if stat > 0
msgbox('Error opening web page, please check browser or internet connection',...
               'Help Page Error');
         end
      end

      % Value changed function: AcidConcentrationNEditField
      function AcidConcentrationNEditFieldValueChanged(app, event)
         value = app.AcidConcentrationNEditField.Value;
         app.ParamTable.Value('HCl') = value;
      end

      % Value changed function: BaseConcentrationNEditField
      function BaseConcentrationNEditFieldValueChanged(app, event)
         value = app.BaseConcentrationNEditField.Value;
         app.ParamTable.Value('NaOH') = value;
      end

      % Value changed function: VolumetitratedLEditField
      function VolumetitratedLEditFieldValueChanged(app, event)
         value = app.VolumetitratedLEditField.Value;
         app.ParamTable.Value('Init_Vol') = value;
      end

      % Value changed function: NaClpercentEditField
      function NaClpercentEditFieldValueChanged(app, event)
         value = app.NaClpercentEditField.Value;
         app.ParamTable.Value('NaClpercent') = value;
      end

      % Menu selected function: OpenAcidTitrationFileMenu
      function OpenAcidTitrationFileMenuSelected(app, event)
         app.OpenAcidTitrationFileButtonPushed()
      end

      % Menu selected function: OpenBaseTitrationFileMenu
      function OpenBaseTitrationFileMenuSelected(app, event)
         app.OpenBaseTitrationFileButtonPushed()
      end

      % Menu selected function: SaveAllDataFilesMenu
      function SaveAllDataFilesMenuSelected(app, event)
         if app.ProgramLevel ~= 5
            msgbox(['Error Saving database file.',...
                  ' Please save after modelling the BC curve'], ...
                  'File save error');
            return;
         end
         %save buffer data as a .mat file (database file)
         ingredient = ABD;                      %empty ABD class
         %generate and label buffer table with columns (conc in molar)
         buffer_table = app.BufferTable.Data;   %get the data as a table
         if width(buffer_table) == 4
            buffer_table(:,4) = [];                %remove BCval column
            buffer_table.Properties.VariableNames={'Conc','pK','a_b'};
         end
         %fill ingredient struct fields 
         ingredient.name = app.IngredientnameEditField.Value;
         ingredient.conc = app.ConcentrationintitrationEditField.Value;
         ingredient.buffer_table = buffer_table;
         ingredient.adjC = app.AdjCvalueMEditField.Value;
         ingredient.pH = app.EstimatedpHEditField.Value;
         ingredient.pH_cal = app.Electrode05pHEditField.Value;
         ingredient.totalBC = app.tBetaEditField.Value;
         %save titration data, input file info, etc
         ingredient.titration = 1;
         ingredient.titration_conc = ...
            app.ConcentrationintitrationEditField.Value;
         ingredient.titration_IS = app.NaClpercentEditField.Value/5.84;
         ingredient.titration_files.HCl_titration_filename = ...
            app.AcidTitrationFilename;
         ingredient.titration_files.NaOH_titration_filename = ...
            app.BaseTitrationFilename;
         ingredient.titration_files.HCl_titration_data = ...
            app.acidtitrationcrv;
         ingredient.titration_files.NaOH_titration_data = ...
            app.basetitrationcrv;
         temptable = app.BufferTable.Data;
         if width(temptable) == 4
            temptable.Properties.VariableNames = {'Conc','pK','a_b','Beta'};
            ingredient.titration_buffertable = temptable;
         end
         ingredient.titration_SSE = app.ModelSSEEditField.Value;
         ingredient.titration_obspH = app.measuredpH;
         ingredient.titration_volume = app.VolumetitratedLEditField.Value;
         ingredient.titration_HClconc = ...
            app.AcidConcentrationNEditField.Value;
         ingredient.titration_NaOHconc = ...
            app.BaseConcentrationNEditField.Value;
         ingredient.titration_SPX = app.SPX;
         if isfield(app.SPX, "Pred")
            ingredient.titration_ModelCurve = app.SPX.Pred;
         end
         ingredient.titration_BCcurve = app.oriBCcurve; 
         if isfield(app.SCBC, "Pred")
            SCBCcurve = app.SCBC.Pred;
            ingredient.titration_SCBCcurve = SCBCcurve;
         end
         ingredient.ver = app.ABDversion;
         %Parameter table
         ingredient.parameters = app.ParamTable;
         %save struct in a .mat file
         try
            fileprefixname = app.IngredientnameEditField.Value;
            [file, path] = uiputfile('*.mat','Save Data Files',...
               fileprefixname);     %get name and path
            if file == 0
               return  
            end
            if app.saveall %not cancel
               mainname = fullfile(path,file);
               [~,filenameonly,~] = fileparts(mainname);
               fileprefix = strcat(filenameonly,'_');
               %save figure
               figname = strcat(fileprefix,'graph.pdf');
               figfilename = fullfile(path,figname); %make fig file path
               exportgraphics(app.UIAxes,figfilename); %save fig 
               %save parameters
               paramname = strcat(fileprefix,'paramfile.csv');
               paramfilename = fullfile(path,paramname); %make param path
               writetable(ingredient.parameters,paramfilename); %save params
               paramfilegeneral = fullfile(path,"paramfile.csv");
               writetable(ingredient.parameters,paramfilegeneral);
               %save buffertable
               buffername = strcat(fileprefix,'buffertable.csv');
               buffertablefilename = fullfile(path,buffername);
               writetable(buffer_table,buffertablefilename);
               %save BC data points
               BCdatacurvename = strcat(fileprefix,'BCdatapoints.csv');
               BCdatacurvefilename = fullfile(path,BCdatacurvename);
               writematrix(app.BCcurve,BCdatacurvefilename);
               %save BC model curve
               if isfield(app.SPX, 'ABmat') %does SPX have data?
                  BCcurvename = strcat(fileprefix,'BCmodelcurve.csv');
                  BCcurvefilename = fullfile(path,BCcurvename);
                  writematrix(app.SPX.Pred,BCcurvefilename);
               end
               %save general BC info
               infoname = strcat(fileprefix,'info.csv');
               infofilename = fullfile(path,infoname);
               ingredient.write_csv(infofilename);
%                infotable = app.get_infotable();
%                writetable(infotable,infofilename);
               %save .mat file
               save(mainname,'ingredient');         %save file
            else                                    %save ONLY database
               mainname = fullfile(path,file);
               save(mainname,'ingredient');         %save file
               app.saveall = 1;                     %reset saveall = true
            end
         catch
            msgbox('Error writing database files, please check location',...
               'Write Error');
         end         
      end

      % Menu selected function: QuitMenu
      function QuitMenuSelected(app, event)
         app.delete;
      end

      % Button pushed function: TrimBeginingButton
      function TrimBeginingButtonPushed(app, event)
         if app.ProgramLevel >= 3
            
            trimbeg = app.TrimbeginningEditField.Value;
            trimend = app.TrimendEditField.Value;
            [BCpts, ~] = size(app.oriBCcurve);     %get total data pts
            app.BCcurve = app.oriBCcurve(trimbeg+1:BCpts,:);
            [BCpts, ~] = size(app.BCcurve);     %get total data pts
            app.BCcurve = app.BCcurve(1:BCpts-trimend,:);
            value = app.Electrode05pHEditField.Value;         
            %this can fix pH electrode calibration errors
            tempBC = app.BCcurve;
            tempBC(:,1) = tempBC(:,1) + value;
            app.BCcurve = tempBC;
            app.plotBCdata([0,0],app.BCcurve);
            app.ProgramLevel = app.setProgramLevel(4);
         else
            app.TrimbeginningEditField.Value = 0;
            app.TrimendEditField.Value = 0;
            app.Electrode05pHEditField.Value = 0;
         end
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.UIFigure = uifigure('Visible', 'off');
         app.UIFigure.Name = 'MATLAB App';
         app.FileMenu = uimenu(app.UIFigure);
         app.FileMenu.Text = 'File';
         app.OpenDatabaseFileMenu = uimenu(app.FileMenu);
         app.OpenDatabaseFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDatabaseFileMenuSelected, true);
         app.OpenDatabaseFileMenu.Accelerator = 'O';
         app.OpenDatabaseFileMenu.Text = 'Open Database File';
         app.OpenAcidTitrationFileMenu = uimenu(app.FileMenu);
         app.OpenAcidTitrationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenAcidTitrationFileMenuSelected, true);
         app.OpenAcidTitrationFileMenu.Accelerator = 'A';
         app.OpenAcidTitrationFileMenu.Text = 'Open Acid Titration File';
         app.OpenBaseTitrationFileMenu = uimenu(app.FileMenu);
         app.OpenBaseTitrationFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBaseTitrationFileMenuSelected, true);
         app.OpenBaseTitrationFileMenu.Accelerator = 'B';
         app.OpenBaseTitrationFileMenu.Text = 'Open Base Titration File';
         app.OpenParameterFileMenu = uimenu(app.FileMenu);
         app.OpenParameterFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenParameterFileMenuSelected, true);
         app.OpenParameterFileMenu.Text = 'Open Parameter File';
         app.SaveDatabaseFileMenu = uimenu(app.FileMenu);
         app.SaveDatabaseFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDatabaseFileMenuSelected, true);
         app.SaveDatabaseFileMenu.Separator = 'on';
         app.SaveDatabaseFileMenu.Accelerator = 'D';
         app.SaveDatabaseFileMenu.Text = 'Save Database File';
         app.SaveAllDataFilesMenu = uimenu(app.FileMenu);
         app.SaveAllDataFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAllDataFilesMenuSelected, true);
         app.SaveAllDataFilesMenu.Accelerator = 'F';
         app.SaveAllDataFilesMenu.Text = 'Save All Data Files';
         app.SaveParameterFileMenu = uimenu(app.FileMenu);
         app.SaveParameterFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveParameterFileMenuSelected, true);
         app.SaveParameterFileMenu.Text = 'Save Parameter File';
         app.QuitMenu = uimenu(app.FileMenu);
         app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
         app.QuitMenu.Separator = 'on';
         app.QuitMenu.Accelerator = 'Q';
         app.QuitMenu.Text = 'Quit';
         app.HelpMenu = uimenu(app.UIFigure);
         app.HelpMenu.Text = 'Help';
         app.OnlineHelpMenu = uimenu(app.HelpMenu);
         app.OnlineHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineHelpMenuSelected, true);
         app.OnlineHelpMenu.Accelerator = 'H';
         app.OnlineHelpMenu.Text = 'Online Help';
         app.AboutMenu = uimenu(app.HelpMenu);
         app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
         app.AboutMenu.Separator = 'on';
         app.AboutMenu.Text = '        About';
         app.UIAxes = uiaxes(app.UIFigure);
         title(app.UIAxes, 'Title')
         xlabel(app.UIAxes, 'X')
         ylabel(app.UIAxes, 'Y')
         zlabel(app.UIAxes, 'Z')
         app.BufferTable = uitable(app.UIFigure);
         app.BufferTable.ColumnName = {'Conc (M)'; 'pK'; 'a/b'; 'Beta'};
         app.BufferTable.RowName = {};
         app.BufferTable.ColumnEditable = [false false true false];
         app.BufferTable.CellEditCallback = createCallbackFcn(app, @BufferTableCellEdit, true);
         app.OpenAcidTitrationFileButton = uibutton(app.UIFigure, 'push');
         app.OpenAcidTitrationFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAcidTitrationFileButtonPushed, true);
         app.OpenAcidTitrationFileButton.Text = 'Open Acid Titration File';
         app.Lamp_acidfile = uilamp(app.UIFigure);
         app.OpenBaseTitrationFileButton = uibutton(app.UIFigure, 'push');
         app.OpenBaseTitrationFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBaseTitrationFileButtonPushed, true);
         app.OpenBaseTitrationFileButton.Text = 'Open Base Titration File';
         app.Lamp_basefile = uilamp(app.UIFigure);
         app.GenerateBCcurveButton = uibutton(app.UIFigure, 'push');
         app.GenerateBCcurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBCcurveButtonPushed, true);
         app.GenerateBCcurveButton.Text = 'Generate BC curve';
         app.Lamp_BCcurve = uilamp(app.UIFigure);
         app.TrimbeginningEditField = uieditfield(app.UIFigure, 'numeric');
         app.TrimbeginningEditField.Limits = [0 50];
         app.TrimbeginningEditField.RoundFractionalValues = 'on';
         app.TrimbeginningEditField.ValueChangedFcn = createCallbackFcn(app, @TrimbeginningEditFieldValueChanged, true);
         app.TrimendEditField = uieditfield(app.UIFigure, 'numeric');
         app.TrimendEditField.Limits = [0 50];
         app.TrimendEditField.RoundFractionalValues = 'on';
         app.TrimendEditField.ValueChangedFcn = createCallbackFcn(app, @TrimendEditFieldValueChanged, true);
         app.OKButton = uibutton(app.UIFigure, 'push');
         app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
         app.OKButton.Text = 'OK';
         app.AcidConcentrationNEditFieldLabel = uilabel(app.UIFigure);
         app.AcidConcentrationNEditFieldLabel.Text = 'Acid Concentration (N)';
         app.AcidConcentrationNEditField = uieditfield(app.UIFigure, 'numeric');
         app.AcidConcentrationNEditField.ValueChangedFcn = createCallbackFcn(app, @AcidConcentrationNEditFieldValueChanged, true);
         app.BaseConcentrationNEditFieldLabel = uilabel(app.UIFigure);
         app.BaseConcentrationNEditFieldLabel.Text = 'Base Concentration (N)';
         app.BaseConcentrationNEditField = uieditfield(app.UIFigure, 'numeric');
         app.BaseConcentrationNEditField.ValueChangedFcn = createCallbackFcn(app, @BaseConcentrationNEditFieldValueChanged, true);
         app.VolumetitratedLEditFieldLabel = uilabel(app.UIFigure);
         app.VolumetitratedLEditFieldLabel.Text = 'Volume titrated (L)';
         app.VolumetitratedLEditField = uieditfield(app.UIFigure, 'numeric');
         app.VolumetitratedLEditField.ValueChangedFcn = createCallbackFcn(app, @VolumetitratedLEditFieldValueChanged, true);
         app.ModelBCCurveButton = uibutton(app.UIFigure, 'push');
         app.ModelBCCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelBCCurveButtonPushed, true);
         app.ModelBCCurveButton.Text = 'Model BC Curve';
         app.Lamp_ModelBC = uilamp(app.UIFigure);
         app.ElectrodeCorrection05pHLabel = uilabel(app.UIFigure);
         app.ElectrodeCorrection05pHLabel.Text = 'Electrode +/- 0.5 pH';
         app.Electrode05pHEditField = uieditfield(app.UIFigure, 'numeric');
         app.Electrode05pHEditField.Limits = [-0.5 0.5];
         app.Electrode05pHEditField.ValueChangedFcn = createCallbackFcn(app, @Electrode05pHEditFieldValueChanged, true);
         app.EstimatedpHEditFieldLabel = uilabel(app.UIFigure);
         app.EstimatedpHEditFieldLabel.Text = 'Estimated pH';
         app.EstimatedpHEditField = uieditfield(app.UIFigure, 'numeric');
         app.EstimatedpHEditField.Editable = 'off';
         app.AdjCvalueMEditFieldLabel = uilabel(app.UIFigure);
         app.AdjCvalueMEditFieldLabel.Text = 'AdjC value (M)';
         app.AdjCvalueMEditField = uieditfield(app.UIFigure, 'numeric');
         app.AdjCvalueMEditField.Editable = 'off';
         app.useAdjCCheckBox = uicheckbox(app.UIFigure);
         app.useAdjCCheckBox.ValueChangedFcn = createCallbackFcn(app, @useAdjCCheckBoxValueChanged, true);
         app.useAdjCCheckBox.Text = 'use AdjC';
         app.useAdjCCheckBox.Value = true;
         app.ModelSSEEditFieldLabel = uilabel(app.UIFigure);
         app.ModelSSEEditFieldLabel.Text = 'Model SSE';
         app.ModelSSEEditField = uieditfield(app.UIFigure, 'numeric');
         app.ModelSSEEditField.Editable = 'off';
         app.IngredientnameEditFieldLabel = uilabel(app.UIFigure);
         app.IngredientnameEditFieldLabel.Text = 'Ingredient name';
         app.IngredientnameEditField = uieditfield(app.UIFigure, 'text');
         app.ConcentrationinTitrationLabel = uilabel(app.UIFigure);
         app.ConcentrationinTitrationLabel.Text = 'Concentration in titration (%)';
         app.ConcentrationintitrationEditField = uieditfield(app.UIFigure, 'numeric');
         app.NaClpercentEditFieldLabel = uilabel(app.UIFigure);
         app.NaClpercentEditFieldLabel.Text = 'NaCl (percent)';
         app.NaClpercentEditField = uieditfield(app.UIFigure, 'numeric');
         app.NaClpercentEditField.ValueChangedFcn = createCallbackFcn(app, @NaClpercentEditFieldValueChanged, true);
         app.ClearallButton = uibutton(app.UIFigure, 'push');
         app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
         app.ClearallButton.Text = 'Clear all';
         app.DirLabel = uilabel(app.UIFigure);
         app.DirLabel.Text = 'Data Folder';
         app.DataFolderEditField = uieditfield(app.UIFigure, 'text');
         app.DataFolderEditField.Editable = 'off';
         app.tBetaEditFieldLabel = uilabel(app.UIFigure);
         app.tBetaEditFieldLabel.Text = 'tBeta';
         app.tBetaEditField = uieditfield(app.UIFigure, 'numeric');
         app.tBetaEditField.Editable = 'off';
         app.TrimBeginingButton = uibutton(app.UIFigure, 'push');
         app.TrimBeginingButton.ButtonPushedFcn = createCallbackFcn(app, @TrimBeginingButtonPushed, true);
         app.TrimBeginingButton.Text = 'Trim Begining';
         app.TrimEndButton = uibutton(app.UIFigure, 'push');
         app.TrimEndButton.Text = 'Trim End';
         % Show the figure after all components are created
         app.UIFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = BufferCapacity3

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.UIFigure)

         % Execute the startup function
         runStartupFcn(app, @startupFcn)

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.UIFigure)
      end
   end
end